classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        EficienciatrmicadelasoldaduraenplacasplanasLabel  matlab.ui.control.Label
        DatosdelMaterialPanel     matlab.ui.container.Panel
        MaterialLabel             matlab.ui.control.Label
        materialnamefield         matlab.ui.control.EditField
        MaterialesListBoxLabel    matlab.ui.control.Label
        materiallistbox           matlab.ui.control.ListBox
        editmaterialbutt          matlab.ui.control.Button
        deletematerialbutt        matlab.ui.control.Button
        addmaterialbutt           matlab.ui.control.Button
        CondTermKWmKLabel         matlab.ui.control.Label
        kmaterialfield            matlab.ui.control.NumericEditField
        CalorespCpJkgKLabel_2     matlab.ui.control.Label
        calormaterialfield        matlab.ui.control.NumericEditField
        Densidadalfakgm3Label_2   matlab.ui.control.Label
        densidadmaterialfield     matlab.ui.control.NumericEditField
        DatosexperimentalesPanel  matlab.ui.container.Panel
        tabledatos                matlab.ui.control.Table
        addtablebut               matlab.ui.control.Button
        namefile4select           matlab.ui.control.EditField
        selectfilebutt            matlab.ui.control.Button
        cleanbutt                 matlab.ui.control.Button
        LongCordondeSoldmLabel    matlab.ui.control.Label
        longcordonfield           matlab.ui.control.NumericEditField
        calcularbtt               matlab.ui.control.Button
        SALIRButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        DatosBase % Description
        listMaterials % Description
        counter % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.tabledatos.ColumnFormat={'char','char','char','numeric','numeric','numeric','numeric','numeric','numeric','numeric','numeric'};
            app.tabledatos.Data=cell(10,11);

            if exist('materialesData.txt')==2
                app.listMaterials=readcell('materialesData.txt');
                [m,n]=size(app.listMaterials);
                app.counter=m;
                app.materiallistbox.Items=app.listMaterials(:,1)';
                
            else
                app.counter=0;
                app.listMaterials=cell(1,4);
            end
            
        end

        % Cell edit callback: tabledatos
        function tabledatosCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
             
        end

        % Button pushed function: addtablebut
        function addtablebutButtonPushed(app, event)
            [m,n]=size(app.tabledatos.Data);
            
            if istable(app.tabledatos.Data)
                app.DatosBase=table2cell(app.tabledatos.Data);
            else
                app.DatosBase=app.tabledatos.Data;
            end
            
            d=cell(m+1,n);
            d(1:m,1:n)=app.DatosBase;
            app.tabledatos.Data=d;
        end

        % Button pushed function: calcularbtt
        function calcularbttPushed(app, event)
            datosTabla=app.tabledatos.Data;
            materialName=app.materialnamefield.Value;
            materialK=app.kmaterialfield.Value;
            materialCalor=app.calormaterialfield.Value;
            materialDensidad=app.densidadmaterialfield.Value;
            
            longCordon=app.longcordonfield.Value;
            
            datosMaterial={materialName,materialK,materialCalor,materialDensidad,longCordon};


            main2(datosTabla,datosMaterial);
        end

        % Button pushed function: selectfilebutt
        function selectfilebuttButtonPushed(app, event)
            data=app.namefile4select.Value;
            table=strcat(data,'.xlsx');

            t=readtable(table);
           
            app.tabledatos.Data=t;
            
        end

        % Button pushed function: cleanbutt
        function cleanbuttButtonPushed(app, event)
            app.tabledatos.Data=cell(10,11);
        end

        % Button pushed function: addmaterialbutt
        function addmaterialbuttButtonPushed(app, event)
            %Tomar la data de las celdas en una variable nuevatemporal para el material con varios
            %elementos
            nameMaterial=app.materialnamefield.Value;
            kMaterial=app.kmaterialfield.Value;
            cpMaterial=app.calormaterialfield.Value;
            alfaMaterial=app.densidadmaterialfield.Value;

            
            [m,n]=size(app.listMaterials);
            
            if app.counter==0
                app.listMaterials(m,1)={nameMaterial};
                app.listMaterials(m,2)={kMaterial};
                app.listMaterials(m,3)={cpMaterial};
                app.listMaterials(m,4)={alfaMaterial};
                app.counter=app.counter+1;

            else
                app.listMaterials(m+1,1)={nameMaterial};
                app.listMaterials(m+1,2)={kMaterial};
                app.listMaterials(m+1,3)={cpMaterial};
                app.listMaterials(m+1,4)={alfaMaterial};
                app.counter=app.counter+1;

            end
            
            app.materiallistbox.Items=app.listMaterials(:,1)';
            writecell(app.listMaterials,'materialesData.txt');                

        end

        % Button pushed function: deletematerialbutt
        function deletematerialbuttButtonPushed(app, event)
            if app.counter>0
                materialselect=app.materiallistbox.Value;
            
                [m,n]=size(app.listMaterials);
            
                for i=1:m
                    if strcmp(materialselect,app.listMaterials{i,1})
                    materialposition=i;
                    break
                    end
                end

                app.listMaterials(materialposition,:)=[];
                app.materiallistbox.Items=app.listMaterials(:,1)';
            
                app.counter=app.counter-1;
                writecell(app.listMaterials,'materialesData.txt')

                if app.counter==0
                    app.listMaterials=cell(1,4);
                    delete("materialesData.txt")
                end
                
            end          
            
        end

        % Value changed function: materiallistbox
        function materiallistboxValueChanged(app, event)
            value = app.materiallistbox.Value;
            [m,n]=size(app.listMaterials);
            
            for i=1:m
                if strcmp(value,app.listMaterials{i,1})
                    materialposition=i;
                    break
                end
            end
            app.materialnamefield.Value=app.listMaterials{materialposition,1};
            app.kmaterialfield.Value=app.listMaterials{materialposition,2};
            app.calormaterialfield.Value=app.listMaterials{materialposition,3};
            app.densidadmaterialfield.Value=app.listMaterials{materialposition,4};
            writecell(app.listMaterials,'materialesData.txt')

        end

        % Button pushed function: editmaterialbutt
        function editmaterialbuttButtonPushed(app, event)
            value = app.materiallistbox.Value;
            [m,n]=size(app.listMaterials);
            
            for i=1:m
                if strcmp(value,app.listMaterials{i,1})
                    materialposition=i;
                    break
                end
            end
            app.listMaterials{materialposition,1}=app.materialnamefield.Value;
            app.listMaterials{materialposition,2}=app.kmaterialfield.Value;
            app.listMaterials{materialposition,3}=app.calormaterialfield.Value;
            app.listMaterials{materialposition,4}=app.densidadmaterialfield.Value;
            writecell(app.listMaterials,'materialesData.txt')
        end

        % Button pushed function: SALIRButton
        function SALIRButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EficienciatrmicadelasoldaduraenplacasplanasLabel = uilabel(app.UIFigure);
            app.EficienciatrmicadelasoldaduraenplacasplanasLabel.Text = 'Eficiencia térmica de la soldadura en placas planas';
            app.DatosdelMaterialPanel = uipanel(app.UIFigure);
            app.DatosdelMaterialPanel.Title = 'Datos del Material';
            app.MaterialLabel = uilabel(app.DatosdelMaterialPanel);
            app.MaterialLabel.Text = 'Material:';
            app.materialnamefield = uieditfield(app.DatosdelMaterialPanel, 'text');
            app.MaterialesListBoxLabel = uilabel(app.DatosdelMaterialPanel);
            app.MaterialesListBoxLabel.Text = 'Materiales:';
            app.materiallistbox = uilistbox(app.DatosdelMaterialPanel);
            app.materiallistbox.Items = {};
            app.materiallistbox.ValueChangedFcn = createCallbackFcn(app, @materiallistboxValueChanged, true);
            app.materiallistbox.Value = {};
            app.editmaterialbutt = uibutton(app.DatosdelMaterialPanel, 'push');
            app.editmaterialbutt.ButtonPushedFcn = createCallbackFcn(app, @editmaterialbuttButtonPushed, true);
            app.editmaterialbutt.Text = 'Editar';
            app.deletematerialbutt = uibutton(app.DatosdelMaterialPanel, 'push');
            app.deletematerialbutt.ButtonPushedFcn = createCallbackFcn(app, @deletematerialbuttButtonPushed, true);
            app.deletematerialbutt.Text = 'Borrar';
            app.addmaterialbutt = uibutton(app.DatosdelMaterialPanel, 'push');
            app.addmaterialbutt.ButtonPushedFcn = createCallbackFcn(app, @addmaterialbuttButtonPushed, true);
            app.addmaterialbutt.Text = 'Añadir';
            app.CondTermKWmKLabel = uilabel(app.DatosdelMaterialPanel);
            app.CondTermKWmKLabel.Text = 'Cond. Term. (K) [W/m*K]';
            app.kmaterialfield = uieditfield(app.DatosdelMaterialPanel, 'numeric');
            app.CalorespCpJkgKLabel_2 = uilabel(app.DatosdelMaterialPanel);
            app.CalorespCpJkgKLabel_2.Text = 'Calor esp. (Cp) [J/kg*K]';
            app.calormaterialfield = uieditfield(app.DatosdelMaterialPanel, 'numeric');
            app.Densidadalfakgm3Label_2 = uilabel(app.DatosdelMaterialPanel);
            app.Densidadalfakgm3Label_2.Text = 'Densidad (alfa) [kg/m3]';
            app.densidadmaterialfield = uieditfield(app.DatosdelMaterialPanel, 'numeric');
            app.DatosexperimentalesPanel = uipanel(app.UIFigure);
            app.DatosexperimentalesPanel.Title = 'Datos experimentales';
            app.tabledatos = uitable(app.DatosexperimentalesPanel);
            app.tabledatos.ColumnName = {'Placa'; 'Punto'; 'Electrodo'; 'Amp [A]'; 'V [V]'; 'e [mm]'; 'X (medición) [m]'; 'Y (medición) [m]'; 't [s]'; 'T0 [°C]'; 'Tf [°C]'};
            app.tabledatos.ColumnEditable = true;
            app.tabledatos.CellEditCallback = createCallbackFcn(app, @tabledatosCellEdit, true);
            app.addtablebut = uibutton(app.DatosexperimentalesPanel, 'push');
            app.addtablebut.ButtonPushedFcn = createCallbackFcn(app, @addtablebutButtonPushed, true);
            app.addtablebut.Text = 'Añadir';
            app.namefile4select = uieditfield(app.DatosexperimentalesPanel, 'text');
            app.selectfilebutt = uibutton(app.DatosexperimentalesPanel, 'push');
            app.selectfilebutt.ButtonPushedFcn = createCallbackFcn(app, @selectfilebuttButtonPushed, true);
            app.selectfilebutt.Text = 'Seleccionar Archivo';
            app.cleanbutt = uibutton(app.DatosexperimentalesPanel, 'push');
            app.cleanbutt.ButtonPushedFcn = createCallbackFcn(app, @cleanbuttButtonPushed, true);
            app.cleanbutt.Text = 'Limpiar';
            app.LongCordondeSoldmLabel = uilabel(app.DatosexperimentalesPanel);
            app.LongCordondeSoldmLabel.Text = {'Long. Cordon '; 'de Sold. [m]'};
            app.longcordonfield = uieditfield(app.DatosexperimentalesPanel, 'numeric');
            app.longcordonfield.Value = 0.1;
            app.calcularbtt = uibutton(app.UIFigure, 'push');
            app.calcularbtt.ButtonPushedFcn = createCallbackFcn(app, @calcularbttPushed, true);
            app.calcularbtt.Text = 'Calcular';
            app.SALIRButton = uibutton(app.UIFigure, 'push');
            app.SALIRButton.ButtonPushedFcn = createCallbackFcn(app, @SALIRButtonPushed, true);
            app.SALIRButton.Text = 'SALIR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end