classdef main2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        EficienciaTab               matlab.ui.container.Tab
        GrficosdeeficcienciaPanel   matlab.ui.container.Panel
        efvselecAxes                matlab.ui.control.UIAxes
        efvsampAxes                 matlab.ui.control.UIAxes
        efvsvelAxes                 matlab.ui.control.UIAxes
        ElectrodosDropDownLabel     matlab.ui.control.Label
        ElectrodosDropDown          matlab.ui.control.DropDown
        VelocidaddeEnfriamientoTab  matlab.ui.container.Tab
        Panel_2                     matlab.ui.container.Panel
        velEnfriamientoAxes         matlab.ui.control.UIAxes
        TabladeResultados           matlab.ui.container.Tab
        namefileresults             matlab.ui.control.EditField
        exportresultsbtt            matlab.ui.control.Button
        SeleccionarFormatoDropDownLabel  matlab.ui.control.Label
        SeleccionarFormatoDropDown  matlab.ui.control.DropDown
        resultadostabla             matlab.ui.control.Table
        SeleccionarFormatoDropDownLabel_2  matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        ResultadostrmicosdesoldaduraenplacasplanasLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        resultadosTable2export % Description
        filtrografico1 % Description
        electrodoCompartir % Description
        resultadosUltimos % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, datosTabla, datosMaterial)
            %Aqui va todo el código para los cálculos y las tablas


            placasDatos=datosTabla{:,1};
            puntosDatos=datosTabla{:,2};
            electrodosDatos=datosTabla{:,3};
            amperajeDatos=datosTabla{:,4};
            voltajeDatos=datosTabla{:,5};
            espesorDatos=datosTabla{:,6};
            xDatos=datosTabla{:,7};
            yDatos=datosTabla{:,8};
            timeDatos=datosTabla{:,9};
            t0Datos=datosTabla{:,10};
            tfDatos=datosTabla{:,11};
            longCordon=datosMaterial{1,5};
            format long
            auxiliar2=unique(electrodosDatos,'stable');
            app.electrodoCompartir=electrodosDatos;
            app.ElectrodosDropDown.Items=auxiliar2;
            %%Calculo para el calor absorbido por el material durante
            % un proceso de soldadura
                        
            %datos material
            namematerial=datosMaterial{1,1};
            kmaterial=datosMaterial{1,2}; %conductividad térmmica del material [W/m*K]
            cpmaterial=datosMaterial{1,3}; %calor específico del material [J/kg*K]
            denmaterial=datosMaterial{1,4};   %densidad del material [kg/m3]
            alfa=kmaterial./(denmaterial.*cpmaterial);  %difusividad térmica [m2/s]
            
            %datos experimentales
            
            vsoldeo=longCordon./timeDatos;  %velocidad de soldeo [m/s]
            r=sqrt((xDatos.^2)+(yDatos.^2));   %posición del termopar [m]
            
            delta=0.003;%Constante
            
            
            s=70; %hc+hr (coeficiente de convección de rad. Autores: Lopez y Estupiñan [W/m2*K]
            b=(2*(s))/(denmaterial*cpmaterial*delta); %factor b [1/s]
            
            %BORAR ESTA LINEA ALTERMINAR
            %Qvarios=[1250.58;665.48;1213.41;927.62;1437.05;959.48;1703.42;1671.63;1842.52;1748.229;1521.23;1730.80;1613.80;1679.44;1793.99;1852.30;1864.02;1922.70;1723.06;1774.73]
            k0=readmatrix('k0valores.txt');      %funcion modificada de bessel de segundo orden
            
            
            %Cálculo del calor absorbido por el material base durante el soldeo
            num=(tfDatos-t0Datos).*2.*delta.*pi.*kmaterial;
            den1=exp(-(vsoldeo.*xDatos)./(2.*alfa));
            den2=r.*sqrt(((vsoldeo./(2.*alfa)).^2)+(b/alfa));
            Q=num./(den1.*k0.*den2);
                       
            %Calculo para comparativa con datos reales
            
            
            %Cálculo de la eficiencia térmica
            Qt=amperajeDatos.*voltajeDatos; %calor suministrado por la fuente[W]
            
            n=(Q./Qt).*100;%eficiencia térmica teórica
            
            %VELOCIDAD DE ENFRIAMIENTO
            
            apt=n.*(voltajeDatos.*amperajeDatos./(vsoldeo.*60));
            v=2.*kmaterial.*pi.*cpmaterial.*(((espesorDatos)./(apt)).^2).*((tfDatos-t0Datos).^3);
            
            
            etiquetasTabla={'Placa','Posición','Espesores[mm]','Posición termopares [m]',...
                'Velocidad de soldeo [m/min]','Calor absorbido durante soldeo Q [W]',...
                'Potencia suministrada por la fuente [W]','Eficiencia térmica n [%]',...
                'Aporte Térmico Líquido [J/m]','Velocidad de enfriamiento [°/min]'};
            
            resultadosFinales=table(placasDatos,puntosDatos,espesorDatos,...
                r,vsoldeo,Q,Qt,n,apt,v,'VariableNames',etiquetasTabla);
            
            app.resultadostabla.ColumnName=etiquetasTabla;
            app.resultadostabla.Data=resultadosFinales;
            
            app.resultadosTable2export=resultadosFinales;
            app.resultadosUltimos=resultadosFinales;
            
            
            %Cuanntos espesores hay
            auxiliar=unique(resultadosFinales{:,3},'stable');
            cantidad=length(auxiliar);
            
            
            hold(app.efvsampAxes)
            grid(app.efvsampAxes,'on')
            

            for i=1:cantidad
                filtro1=find(resultadosFinales{:,3}==auxiliar(i));
                
                n_filtradas=resultadosFinales{filtro1,8};
                amperajes_filtrados=amperajeDatos(filtro1);
                paraplot=[n_filtradas,amperajes_filtrados];
                
                [~, s] = sort(paraplot(:, 1));
                paraplotcorregido=paraplot(s, :);


                if i==1
                    
                    plot(app.efvsampAxes,paraplotcorregido(:,2),paraplotcorregido(:,1),'o','MarkerSize',10,'MarkerEdgeColor','b')
                    legend(app.efvsampAxes,'3 mm','5mm');
                elseif i==2

                    plot(app.efvsampAxes,paraplotcorregido(:,2),paraplotcorregido(:,1),'o','MarkerSize',10,'MarkerEdgeColor','r')
                    legend(app.efvsampAxes,'3 mm','5mm');
                elseif i==3

                    plot(app.efvsampAxes,paraplotcorregido(:,2),paraplotcorregido(:,1),'o','MarkerSize',10,'MarkerEdgeColor','c')
                    legend(app.efvsampAxes,'3 mm','5mm');
                elseif i==4

                    plot(app.efvsampAxes,paraplotcorregido(:,2),paraplotcorregido(:,1),'o','MarkerSize',10,'MarkerEdgeColor','y')
                    legend(app.efvsampAxes,'3 mm','5mm');
                elseif i==5

                    plot(app.efvsampAxes,paraplotcorregido(:,2),paraplotcorregido(:,1),'o','MarkerSize',10,'MarkerEdgeColor','k')
                    legend(app.efvsampAxes,'3 mm','5mm');
                else
                    break
                end
            end
            
            %GRAFICO 2
            filtro2=find(strcmp(electrodosDatos,app.ElectrodosDropDown.Value));
            cantidad2=length(filtro2);
            
            x=1:cantidad2;
            eficienciasPlot=resultadosFinales{filtro2,8};
            bar(app.efvselecAxes, x, eficienciasPlot);
            
            %GRAFICO 3
            
            plot(app.efvsvelAxes,vsoldeo,n,'s','MarkerSize',12,'MarkerEdgeColor','b','MarkerFaceColor',[0.5,0.5,0.5])
            
            %Grafico4
            plot(app.velEnfriamientoAxes,tfDatos,v,'s','LineWidth',2,'MarkerSize',12,'MarkerEdgeColor','b','MarkerFaceColor',[0.5,0.5,0.5])
        end

        % Button pushed function: exportresultsbtt
        function exportresultsbttButtonPushed(app, event)
            value=app.SeleccionarFormatoDropDown.Value;
            
            if strcmp(value,'Excel (.xlsx)')
                format='.xlsx';
            else
                format='.txt';
            end
            
            filename=app.namefileresults.Value;
            nombrearchivo=strcat(filename,format);
            writetable(app.resultadosTable2export,nombrearchivo);
        end

        % Value changed function: ElectrodosDropDown
        function ElectrodosDropDownValueChanged(app, event)
            value = app.ElectrodosDropDown.Value;
            
            filtro2=find(strcmp(app.electrodoCompartir,value));
            cantidad2=length(filtro2);
            
            x=1:cantidad2;
            eficienciasPlot=app.resultadosUltimos{filtro2,8};
            bar(app.efvselecAxes, x, eficienciasPlot);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EficienciaTab = uitab(app.TabGroup);
            app.EficienciaTab.Title = 'Eficiencia';
            app.GrficosdeeficcienciaPanel = uipanel(app.EficienciaTab);
            app.GrficosdeeficcienciaPanel.Title = 'Gráficos de eficciencia';
            app.efvselecAxes = uiaxes(app.GrficosdeeficcienciaPanel);
            title(app.efvselecAxes, 'Eficiencia vs Electrodo')
            xlabel(app.efvselecAxes, '# Electrodo')
            ylabel(app.efvselecAxes, 'Eficiencia [n %]')
            app.efvselecAxes.YLim = [0 100];
            app.efvsampAxes = uiaxes(app.GrficosdeeficcienciaPanel);
            title(app.efvsampAxes, 'Eficiencia vs Amperaje')
            xlabel(app.efvsampAxes, 'Amperaje [A]')
            ylabel(app.efvsampAxes, 'Eficiencia [n]')
            app.efvsampAxes.XLim = [70 150];
            app.efvsampAxes.YLim = [0 100];
            app.efvsvelAxes = uiaxes(app.GrficosdeeficcienciaPanel);
            title(app.efvsvelAxes, 'Eficiencia vs Velocidad de Soldeo')
            xlabel(app.efvsvelAxes, 'Velocidad de Soldeo [m/s]')
            ylabel(app.efvsvelAxes, 'Eficiencia [n %]')
            app.efvsvelAxes.YLim = [0 100];
            app.ElectrodosDropDownLabel = uilabel(app.GrficosdeeficcienciaPanel);
            app.ElectrodosDropDownLabel.Text = 'Electrodos';
            app.ElectrodosDropDown = uidropdown(app.GrficosdeeficcienciaPanel);
            app.ElectrodosDropDown.Items = {};
            app.ElectrodosDropDown.ValueChangedFcn = createCallbackFcn(app, @ElectrodosDropDownValueChanged, true);
            app.ElectrodosDropDown.Value = {};
            app.VelocidaddeEnfriamientoTab = uitab(app.TabGroup);
            app.VelocidaddeEnfriamientoTab.Title = 'Velocidad de Enfriamiento';
            app.Panel_2 = uipanel(app.VelocidaddeEnfriamientoTab);
            app.velEnfriamientoAxes = uiaxes(app.Panel_2);
            title(app.velEnfriamientoAxes, 'VELOCIDAD DE ENFRIAMIENTO VS TEMPERATURA CRÍTICA DE SOLDEO')
            xlabel(app.velEnfriamientoAxes, 'Temperatura Crítica [°]')
            ylabel(app.velEnfriamientoAxes, 'Velocidad de Enfriamiento [°C/min]')
            app.TabladeResultados = uitab(app.TabGroup);
            app.TabladeResultados.Title = 'Tabla de Resultados';
            app.namefileresults = uieditfield(app.TabladeResultados, 'text');
            app.exportresultsbtt = uibutton(app.TabladeResultados, 'push');
            app.exportresultsbtt.ButtonPushedFcn = createCallbackFcn(app, @exportresultsbttButtonPushed, true);
            app.exportresultsbtt.Text = 'Exportar Resultados';
            app.SeleccionarFormatoDropDownLabel = uilabel(app.TabladeResultados);
            app.SeleccionarFormatoDropDownLabel.Text = 'Seleccionar Formato';
            app.SeleccionarFormatoDropDown = uidropdown(app.TabladeResultados);
            app.SeleccionarFormatoDropDown.Items = {'Excel (.xlsx)', 'Texto (.txt)'};
            app.SeleccionarFormatoDropDown.Value = 'Excel (.xlsx)';
            app.resultadostabla = uitable(app.TabladeResultados);
            app.resultadostabla.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.resultadostabla.RowName = {};
            app.SeleccionarFormatoDropDownLabel_2 = uilabel(app.TabladeResultados);
            app.SeleccionarFormatoDropDownLabel_2.Text = 'Nombre Archivo';
            app.Panel = uipanel(app.UIFigure);
            app.ResultadostrmicosdesoldaduraenplacasplanasLabel = uilabel(app.Panel);
            app.ResultadostrmicosdesoldaduraenplacasplanasLabel.Text = 'Resultados térmicos de soldadura en placas planas';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end